classdef diffShot_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        LeftPanel2               matlab.ui.container.Panel
        SliceSliderLabel         matlab.ui.control.Label
        SliceSlider              matlab.ui.control.Slider
        RunDynamicsButton        matlab.ui.control.Button
        LoadimageButton          matlab.ui.control.Button
        diffShotButton           matlab.ui.control.Button
        ImageylimEditFieldLabel  matlab.ui.control.Label
        ImageylimEditField       matlab.ui.control.NumericEditField
        EditField                matlab.ui.control.NumericEditField
        HistxlimEditFieldLabel   matlab.ui.control.Label
        HistxlimEditField        matlab.ui.control.NumericEditField
        EditField_2              matlab.ui.control.NumericEditField
        HistbinsEditFieldLabel   matlab.ui.control.Label
        HistbinsEditField        matlab.ui.control.NumericEditField
        PauseButton              matlab.ui.control.StateButton
        ResetButton              matlab.ui.control.StateButton
        SavegifCheckBox          matlab.ui.control.CheckBox
        CenterPanel              matlab.ui.container.Panel
        RightPanel2              matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        RightPanel               matlab.ui.container.Panel
        UIAxes2                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        scanParams; % Description
    end

    methods (Access = public)


        function checkPauseStopStatus(app, flag)
            % This function can be called at any time to check that status of the pause and stop buttons.
            % If paused, it will wait until un-paused.
            % If stopped, it will throw an error to break execution. The error will not be thrown.
            % If flag input is included and =1, buttons will be reset to de-selected.
            % if flag input is included and =2, all buttons will be disabled (used during file writing and other processes)
            % if flag input is included and =3, all buttons will be enabled (used during file writing and other processes)
            if nargin > 1
                switch flag
                    case 1 % reset pause/stop button
                        app.PauseButton.Value = false;
                        PauseButtonValueChanged(app, [])
%                         app.StopButton.Value = false;
                    case 2 % disable buttons
                        app.PauseButton.Enable = 'off';
%                         app.StopButton.Enable = 'off';
                    case 3 % enable buttons
                        app.PauseButton.Enable = 'on';
%                         app.StopButton.Enable = 'on';
                    otherwise
                        error('Unknown flag value.')
                end
                drawnow()
            else
%                 if app.StopButton.Value
%                     checkPauseStopStatus(app,1) % reset pause/stop buttons
%                     error('MATLAB:diffShot_app:abort','User aborted execution.')
%                 end
                if app.PauseButton.Value
                    waitfor(app.PauseButton,'Value',false)
                end
            end


        end
        
        function broken = checkResetStatus(app)
            
            %keyboard
            if app.ResetButton.Value
                % reset the state, break out of dyn/diffshot code
                broken = true;

            else
                broken = false;
                
            end

            
        end
    end

    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, scanParams)
            
        end

        % Callback function: LoadimageButton, UIAxes, UIAxes2
        function LoadimageButtonPushed(app, event)
            [filenames,pathname] = uigetfile({'*.img;*.nii;*.nii.gz','Nifti/Analyze files'},'Select the scans you want a report on... ', 'multiselect', 'on');
            
           	if ischar(filenames)
             	  filenames = {filenames};
           	elseif isnumeric(filenames)
             	  return;
            end
            cd(pathname);
            %filepath = [pathname filenames{1}];
            app.scanParams = setInitialParams_app(filenames);
            %app.scanParams = setInitialParams_app(filenames);
            app.scanParams.mypath = pathname;
            volume = niftiread(app.scanParams(1).fileName);

            %SliceSliderValueChanging(app,event)
            SliceSliderValueChanged(app)
            %DynamicSliderValueChanged(app)
            thisSlice = 1;
            thisDyn = 1;

            defaultShow = volume(:,:,thisSlice,thisDyn);


            imagesc(app.UIAxes, defaultShow);
            colorbar(app.UIAxes)

            
            histogram(app.UIAxes2, nonzeros(defaultShow(:)));

            
            %colormap(app.UIAxes, viridis)
            
            app.scanParams.vol = double(volume);

            %imagesc(volume(:,:,thisSlice, thisDyn));


        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            changingValue = event.Value;

            [~, minIdx] = min(abs(changingValue - event.Source.MajorTicks(:)));
            event.Source.Value = event.Source.MajorTicks(minIdx);
            changingValue = event.Source.MajorTicks(minIdx);

            imagesc(app.UIAxes, app.scanParams.vol(:,:,changingValue,1));
            colorbar(app.UIAxes)
            if strcmpi('2022b',version('-release'))
                clim(app.UIAxes,[app.ImageylimEditField.Value app.EditField.Value])
            else
                caxis(app.UIAxes,[app.ImageylimEditField.Value app.EditField.Value])
            end

            defaultShowHere = app.scanParams.vol(:,:,changingValue,1);
        
            histogram(app.UIAxes2, nonzeros(defaultShowHere(:)),app.HistbinsEditField.Value);
            xlim(app.UIAxes2, [app.HistxlimEditField.Value app.EditField_2.Value]);
            %xticklabels(app.UIAxes2,[0 max(defaultShowHere(:))])

        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            %value = app.SliceSlider.Value;

            app.SliceSlider.Limits = [app.scanParams.sliceSelectFirst app.scanParams.sliceSelectLast];
            %app.SliceSlider.Value = 1;
            app.SliceSlider.MajorTicks = 1:app.SliceSlider.Limits(2);
        end

        % Button pushed function: RunDynamicsButton
        function RunDynamicsButtonPushed(app, event)
            
                for ii = 1:app.scanParams.volumeSelect
                    imagesc(app.UIAxes, app.scanParams.vol(:,:,round(app.SliceSlider.Value),ii));
                    
                    defaultShowHere = app.scanParams.vol(:,:,round(app.SliceSlider.Value),ii);

                    histogram(app.UIAxes2, nonzeros(defaultShowHere(:)),app.HistbinsEditField.Value);
                    xlim(app.UIAxes2, [app.HistxlimEditField.Value app.EditField_2.Value]);
                    colormap(app.UIAxes, viridis)
                    %tmp = app.scanParams.vol(:,:,round(app.SliceSlider.Value),ii);
                    %tmpx = max(tmp(:));
                    colorbar(app.UIAxes)
                    %clim(app.UIAxes,[app.ImageylimEditField.Value app.EditField.Value])
                    if strcmpi('2022b',version('-release'))
                        clim(app.UIAxes,[app.ImageylimEditField.Value app.EditField.Value])
                    else
                        caxis(app.UIAxes,[app.ImageylimEditField.Value app.EditField.Value])
                    end
                                

                    title(app.UIAxes, ['Dynamic: ' num2str(ii)])
                    pause(0.05)

                    if app.SavegifCheckBox.Value
                        if strcmpi('2022b',version('-release'))
                            exportgraphics(app.UIAxes,[app.scanParams.mypath extractBefore(app.scanParams.fileName,'.') 'dynamic_stack.gif'],'Append',true);
                        else
                            frame = getframe(app.UIAxes);
                            im{ii} = frame2im(frame);
                            [imind, cm] = rgb2ind(im{ii},256);
                            if ii == 1
                                imwrite(imind,cm,[app.scanParams.mypath extractBefore(app.scanParams.fileName,'.') 'dynamic_stack.gif'],'gif','LoopCount',inf,"DelayTime",0.5);
                            else
                                imwrite(imind,cm,[app.scanParams.mypath extractBefore(app.scanParams.fileName,'.') 'dynamic_stack.gif'],'gif','WriteMode','append',"DelayTime",0.5);
                            end
                            
                        end
                    end


                    % Check if process is paused
                    if ~isempty(app)
                        checkPauseStopStatus(app)
                    end

                    [broken] = checkResetStatus(app);
                    if broken == true
                        break
                    end


                    %keyboard
                end


        end

        % Value changed function: ResetButton
        function ResetButtonPushed(app, event)

            if app.ResetButton.Value
                %                 app.StopButton.Enable = 'off';
                app.ResetButton.Text = 'Unset';
            else
                %                 app.StopButton.Enable = 'on';
                app.ResetButton.Text = 'Reset';
            end


            %keyboard
            imagesc(app.UIAxes, app.scanParams.vol(:,:,round(app.SliceSlider.Value),1));
            title(app.UIAxes, ['Dynamic: ' num2str(1)])
            %getmax = app.scanParams.vol(:,:,:,1);
            %mymax = max(getmax(:));
            colorbar(app.UIAxes)
            %clim(app.UIAxes, [app.ImageylimEditField.Value app.EditField.Value]);
            if strcmpi('2022b',version('-release'))
                clim(app.UIAxes,[app.ImageylimEditField.Value app.EditField.Value])
            else
                caxis(app.UIAxes,[app.ImageylimEditField.Value app.EditField.Value])
            end
            defaultShowHere = app.scanParams.vol(:,:,round(app.SliceSlider.Value),1);

            histogram(app.UIAxes2, nonzeros(defaultShowHere(:)),app.HistbinsEditField.Value);
            xlim(app.UIAxes2, [app.HistxlimEditField.Value app.EditField_2.Value]); %max(defaultShowHere(:))])
            %xticklabels(app.UIAxes2,[0 max(defaultShowHere(:))])
            %xticks(app.UIAxes2)


        end

        % Button pushed function: diffShotButton
        function diffShotButtonPushed(app, event)
            %data = double(data);


            %app.ImageylimEditField.Value = -10;
            %app.EditField.Value = 30;

            
            data = app.scanParams.vol;
            data = data(:,:,:,1:end-1);

            nX = size(data,1);
            nY = size(data,2);
            nS = size(data,3);
            nV = size(data,4);

            %stack_mean = mean(data,4);
            %thisstack_mean = stack_mean(:,:,round(app.SliceSlider.Value));

            % here we take one slice to speed things up
            data = data(:,:,round(app.SliceSlider.Value),:);
            thisstack_mean = mean(data,4);
            %threshold by 5% of the max
            myidx = data>0.1*max(data(:));

%             for ii = 1:nS
%                 for jj = 1:nV
%                     IE(:,:,ii,jj) = edge(myidx(:,:,ii,jj));
%                     IC(:,:,ii,jj) = imcomplement(IE(:,:,ii,jj));
%                 end
%             end
% 
% %             IE = edge(myidx);
% %             IC = imcomplement(IE);
%             myidx_patchy = myidx.*IC;

            %test = myidx(:,:,17,1);
            %ifilled = imfill(test,'holes');
            %figure, imagesc(ifilled)


            ifilled = imfill(myidx,'holes');

            
            %tmp = data.*myidx;
            tmp = data.*ifilled;
            
            %figure, imagesc(myidx(:,:,17,1))
            %predata = data;
            
            % use 3x mean rule inside head

            %stack_mean = mean(tmp,4);

            %meanthr = mean(stack_mean(:).*3);
            %myidx2 = tmp<(meanthr);
            %data = tmp.*myidx2;
            data = tmp;


            %diffShot_stack = zeros(nX,nY,nS, nV-1);
            diffShot_stack = zeros(nX,nY,1, nV-1);
            thedifference_logged = zeros(nX, nY, nV-1);
            for ii = 1:nV-1

                
                %thedifference = data(:,:,:,ii) - data(:,:,:,(ii+1));
                thedifference = data(:,:,1,ii) - data(:,:,1,(ii+1));
                
                
                thedifference_logged(:,:,ii) = thedifference;
       

                %myidx = thedifference>0.01*max(thedifference(:));
                %tmp = thedifference.*myidx;
                %thedifference = tmp;
                %clear tmp

                %thediffovermean = thedifference./stack_mean;
                thediffovermean = thedifference./thisstack_mean; 
                prcsigch = thediffovermean .* 100;


                %diffShot_stack(:,:,:,ii) = prcsigch;
                diffShot_stack(:,:,1,ii) = prcsigch;

                %imagesc(app.UIAxes, diffShot_stack(:,:,round(app.SliceSlider.Value),ii))
                %defaultShowHere = diffShot_stack(:,:,round(app.SliceSlider.Value),ii);

                imagesc(app.UIAxes, diffShot_stack(:,:,1,ii))
                defaultShowHere = diffShot_stack(:,:,1,ii);

                histogram(app.UIAxes2, nonzeros(defaultShowHere(:)), app.HistbinsEditField.Value);
                %xlim(app.UIAxes2,[ min(defaultShowHere(:)) max(defaultShowHere(:)) ])
                xlim(app.UIAxes2,[ app.HistxlimEditField.Value app.EditField_2.Value ])
                    
                title(app.UIAxes2,'Histogram')
                title(app.UIAxes, ['Dynamic pair: ' num2str(ii) '-' num2str(ii+1)])

                pause(0.1)

                colormap(app.UIAxes, viridis)
                colorbar(app.UIAxes)
                %myclim = max(max(diffShot_stack(:,:,ii)));
                %myclim = max(max(diffShot_stack(:,:,1)));
                
                %clim(app.UIAxes,[app.ImageylimEditField.Value app.EditField.Value])
                if strcmpi('2022b',version('-release'))
                    clim(app.UIAxes,[app.ImageylimEditField.Value app.EditField.Value])
                else
                    caxis(app.UIAxes,[app.ImageylimEditField.Value app.EditField.Value])
                end
                            
                if app.SavegifCheckBox.Value
                    %exportgraphics(app.UIAxes,[app.scanParams.mypath extractBefore(app.scanParams.fileName,'.') 'diffShot_stack.gif'],'Append',true);
                    if strcmpi('2022b',version('-release'))
                        exportgraphics(app.UIAxes,[app.scanParams.mypath extractBefore(app.scanParams.fileName,'.') 'diffShot_stack.gif'],'Append',true);
                    else
                        frame = getframe(app.UIAxes);
                        im{ii} = frame2im(frame);
                        [imind, cm] = rgb2ind(im{ii},256);
                        if ii == 1
                            imwrite(imind,cm,[app.scanParams.mypath extractBefore(app.scanParams.fileName,'.') 'diffShot_stack.gif'],'gif','LoopCount',inf,"DelayTime",0.5);
                        else
                            imwrite(imind,cm,[app.scanParams.mypath extractBefore(app.scanParams.fileName,'.') 'diffShot_stack.gif'],'gif','WriteMode','append',"DelayTime",0.5);
                        end
                        
                    end
                end

                % Check if process is paused
                if ~isempty(app)
                    checkPauseStopStatus(app)
                end

                [broken] = checkResetStatus(app);
                if broken == true
                    break
                end
            end

            %keyboard

            meandiffs = mean(thedifference_logged,3);
            mytext = sprintf('mean of mean diff shot timeseries: %.5f', mean(meandiffs(:)));
            disp(mytext);


        end

        % Value changed function: HistbinsEditField
        function HistbinsEditFieldValueChanged(app, event)
            value = app.HistbinsEditField.Value;


        end

        % Value changed function: ImageylimEditField
        function ImageylimEditFieldValueChanged(app, event)
            value = app.ImageylimEditField.Value;
            %ylim min
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            %ylim max
            value = app.EditField.Value;
            
        end

        % Value changed function: HistxlimEditField
        function HistxlimEditFieldValueChanged(app, event)
            % xlim min
            value = app.HistxlimEditField.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            % xlim max
            value = app.EditField_2.Value;
            
        end

        % Value changed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.PauseButton.Value
%                 app.StopButton.Enable = 'off';
                app.PauseButton.Text = 'Resume';
            else
%                 app.StopButton.Enable = 'on';
                app.PauseButton.Text = 'Pause';
            end
        end

        % Value changed function: SavegifCheckBox
        function SavegifCheckBoxValueChanged(app, event)
            value = app.SavegifCheckBox.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {510, 510, 510};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {510, 510};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x', 312};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel2 = uipanel(app.LeftPanel);
            app.SliceSliderLabel = uilabel(app.LeftPanel2);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.LeftPanel2);
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.MinorTicks = [];
            app.RunDynamicsButton = uibutton(app.LeftPanel2, 'push');
            app.RunDynamicsButton.ButtonPushedFcn = createCallbackFcn(app, @RunDynamicsButtonPushed, true);
            app.RunDynamicsButton.Text = 'RunDynamics';
            app.LoadimageButton = uibutton(app.LeftPanel2, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.diffShotButton = uibutton(app.LeftPanel2, 'push');
            app.diffShotButton.ButtonPushedFcn = createCallbackFcn(app, @diffShotButtonPushed, true);
            app.diffShotButton.Text = 'diffShot';
            app.ImageylimEditFieldLabel = uilabel(app.LeftPanel2);
            app.ImageylimEditFieldLabel.Text = 'Image ylim';
            app.ImageylimEditField = uieditfield(app.LeftPanel2, 'numeric');
            app.ImageylimEditField.ValueChangedFcn = createCallbackFcn(app, @ImageylimEditFieldValueChanged, true);
            app.EditField = uieditfield(app.LeftPanel2, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1000;
            app.HistxlimEditFieldLabel = uilabel(app.LeftPanel2);
            app.HistxlimEditFieldLabel.Text = 'Hist xlim';
            app.HistxlimEditField = uieditfield(app.LeftPanel2, 'numeric');
            app.HistxlimEditField.ValueChangedFcn = createCallbackFcn(app, @HistxlimEditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.LeftPanel2, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 1000;
            app.HistbinsEditFieldLabel = uilabel(app.LeftPanel2);
            app.HistbinsEditFieldLabel.Text = 'Hist bins';
            app.HistbinsEditField = uieditfield(app.LeftPanel2, 'numeric');
            app.HistbinsEditField.ValueChangedFcn = createCallbackFcn(app, @HistbinsEditFieldValueChanged, true);
            app.HistbinsEditField.Value = 128;
            app.PauseButton = uibutton(app.LeftPanel2, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ResetButton = uibutton(app.LeftPanel2, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SavegifCheckBox = uicheckbox(app.LeftPanel2);
            app.SavegifCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavegifCheckBoxValueChanged, true);
            app.SavegifCheckBox.Text = 'Save gif';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel2 = uipanel(app.CenterPanel);
            app.UIAxes = uiaxes(app.RightPanel2);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.RightPanel);
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = diffShot_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end