classdef fMRI_report_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ChoosefilesButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        RedoHTMLButton                  matlab.ui.control.Button
        DrawROIButton                   matlab.ui.control.Button
        DrawPolyButton                  matlab.ui.control.Button
        LastsliceEditFieldLabel         matlab.ui.control.Label
        LastsliceEditField              matlab.ui.control.EditField
        tSNRColorbarmaxEditFieldLabel   matlab.ui.control.Label
        tSNRColorbarmaxEditField        matlab.ui.control.NumericEditField
        ColormapEditFieldLabel          matlab.ui.control.Label
        ColormapEditField               matlab.ui.control.EditField
        OrientationEditFieldLabel       matlab.ui.control.Label
        OrientationEditField            matlab.ui.control.NumericEditField
        RunreportButton                 matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        fMRIreportLabel                 matlab.ui.control.Label
        FirstsliceEditFieldLabel        matlab.ui.control.Label
        FirstsliceEditField             matlab.ui.control.EditField
        FirstdynamicEditFieldLabel      matlab.ui.control.Label
        FirstdynamicEditField           matlab.ui.control.EditField
        LastdynamicEditFieldLabel       matlab.ui.control.Label
        LastdynamicEditField            matlab.ui.control.EditField
        RESETButton                     matlab.ui.control.Button
        MeanColorbarmaxEditFieldLabel   matlab.ui.control.Label
        MeanColorbarmaxEditField        matlab.ui.control.EditField
        AppbyMichaelAsgharCodebyKevinAquinoLabel  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        Button                          matlab.ui.control.Button
        FDButton                        matlab.ui.control.Button
        DiscardlastdynamicSwitchLabel   matlab.ui.control.Label
        DiscardlastdynamicSwitch        matlab.ui.control.Switch
        UsenoisescanforiSNRSwitchLabel  matlab.ui.control.Label
        UsenoisescanforiSNRSwitch       matlab.ui.control.Switch
        Lamp_3                          matlab.ui.control.Lamp
        Max5ofMaxsignalSwitchLabel      matlab.ui.control.Label
        Max5ofMaxsignalSwitch           matlab.ui.control.Switch
    end

    
    properties (Access = public)
        scanParams % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.scanParams.mask = 1;
            %eval(['Colormap =' app.ColormapEditField.Value ';']);
            %app.scanParams.cmap = Colormap;
            %app.options.imgScale = str2double()
            %keyboard
            app.Button.Icon = 'aesthetics/logo.png';
        end

        % Button pushed function: ChoosefilesButton
        function ChoosefilesButtonPushed(app, event)
           	% Select the the scans that we want to use.
           	[filenames,pathname] = uigetfile({'*.img;*.nii;*.nii.gz','Nifti/Analyze files'},'Select the scans you want a report on... ', 'multiselect', 'on');
            
           	if ischar(filenames)
             	  filenames = {filenames};
           	elseif isnumeric(filenames)
             	  return;
           	end
            
           	% Make a folder called QA_report, where the tSNR maps are calculated, and
           	% where the summary file will live once this is run.
           	cd(pathname);
           	if(~isfolder([pathname '/QA_report']))
           	    mkdir('QA_report');
           	end
            
           	% intialize initial parameters from the nifti headers.
           	app.scanParams = setInitialParams_app(filenames);
            
            dat = cell(length(app.scanParams),8);
            % Here just assigning dat
            for nf=1:length(app.scanParams)
                dat(nf,1:8) = [{app.scanParams(nf).fileName},{nf},{app.scanParams(nf).notes},...
                    {app.scanParams(nf).dynNOISEscan},{app.scanParams(nf).volumeSelectFirst},{app.scanParams(nf).volumeSelect},...
                    {app.scanParams(nf).sliceSelectFirst}, {app.scanParams(nf).sliceSelectLast}];
            end
            
            app.UITable.Data = dat;
            
            % set some defaults from the table
            for ii = 1:length(app.scanParams)
                app.scanParams(ii).mask = 1;
                app.FirstsliceEditField.Value = num2str(app.UITable.Data{ii,7});
                app.LastsliceEditField.Value = num2str(app.UITable.Data{ii,8});
                app.FirstdynamicEditField.Value = num2str(app.UITable.Data{ii,5});
                app.LastdynamicEditField.Value = num2str(app.UITable.Data{ii,6});
                app.scanParams(ii).mypath = pathname;
            end
            % run this to get eval to save out the cmap from our input
            % string
            ColormapEditFieldValueChanged(app, event);
            
        end

        % Value changed function: DiscardlastdynamicSwitch
        function DiscardlastdynamicSwitchValueChange(app, event)
            % functionality for the lamp switching on
            value = app.DiscardlastdynamicSwitch.Value;
            if strcmpi(value,'On')
                
                app.Lamp.Color = [0 1 0];
                
                for ii = 1:length(app.scanParams)
                    % get rid of last dyn
                    app.scanParams(ii).volumeSelect = app.scanParams(ii).volumeSelect-1;
                end
            elseif strcmpi(value,'Off')
                
                app.Lamp.Color = [0.8 0.8 0.8];
                
                for ii = 1:length(app.scanParams)
                    % otherwise grab the last dyn from the table
                    app.scanParams(ii).volumeSelect = app.UITable.Data{ii,6};
                end
            end
        end

        % Callback function
        function FirstsliceEditFieldValueChanged(app, event)
            value = app.FirstsliceEditField.Value;
            for ii = 1:length(app.scanParams)
                app.scanParams(ii).sliceSelectFirst = str2double(value);
            end
            
        end

        % Value changed function: LastsliceEditField
        function LastsliceEditFieldValueChanged(app, event)
            %
            
            %value = app.UITable.Data{8};
            %app.UITable.DisplayData;
            value = app.LastsliceEditField.Value;
            for ii = 1:length(app.scanParams)
                app.scanParams(ii).sliceSelectLast = str2double(value);
            end
        end

        % Callback function
        function FirstdynamicEditFieldValueChanged(app, event)
            value = app.FirstdynamicEditField.Value;
            for ii = 1:length(app.scanParams)
                app.scanParams(ii).volumeSelectFirst = str2double(value);
            end
        end

        % Callback function
        function LastdynamicEditFieldValueChanged(app, event)
            value = app.LastdynamicEditField.Value;
            for ii = 1:length(app.scanParams)
                app.scanParams(ii).volumeSelect = str2double(value);
            end
        end

        % Button pushed function: RedoHTMLButton
        function RedoHTMLButtonPushed(app, event)
            disp('Running HTML only....');
            app.scanParams.recalculateTSNR = 0;
            tSNR_report_app(app,app.scanParams);
            mean_image_report_app(app,app.scanParams);
            generateHTMLReport_app(app.scanParams);
        end

        % Value changed function: ColormapEditField
        function ColormapEditFieldValueChanged(app, event)
            value = app.ColormapEditField.Value;
            try
                eval(['Colormap =' value ';']);
                if(size(Colormap,2)==3)
                    Colormap = Colormap.';
                elseif(size(Colormap,1)==3)
                    % Do nothing because this is right;
                else
                    disp(['Error! Colormap is not in the right format must be a matrix! e.g. hot(255).']);
                    Colormap = hot(255).';
                    app.ColormapEditField.Value = 'hot(255)';
                end
            catch
                disp(['Error! cmap is not in the right format must be a matrix! e.g. hot(255).']);
                Colormap = hot(255).';
                app.ColormapEditField.Value = 'hot(255)';
            end
            
            for ii = 1:length(app.scanParams)
                app.scanParams(ii).cmap = Colormap;
            end
            
            
        end

        % Value changed function: OrientationEditField
        function OrientationEditFieldValueChanged(app, event)
            value = app.OrientationEditField.Value;
            for nf=1:length(app.scanParams)
                app.scanParams(nf).orientation = value;
            end
        end

        % Value changed function: FirstsliceEditField
        function FirstsliceEditFieldValueChanged2(app, event)
            value = app.FirstsliceEditField.Value;
            %value = app.FirstsliceEditField.Value;
            for ii = 1:length(app.scanParams)
                app.scanParams(ii).sliceSelectFirst = str2double(value);
            end
        end

        % Value changed function: FirstdynamicEditField
        function FirstdynamicEditFieldValueChanged2(app, event)
            value = app.FirstdynamicEditField.Value;
            %value = app.FirstdynamicEditField.Value;
            for ii = 1:length(app.scanParams)
                app.scanParams(ii).volumeSelectFirst = str2double(value);
            end
        end

        % Value changed function: LastdynamicEditField
        function LastdynamicEditFieldValueChanged2(app, event)
            value = app.LastdynamicEditField.Value;
            %value = app.LastdynamicEditField.Value;
            for ii = 1:length(app.scanParams)
                app.scanParams(ii).volumeSelect = str2double(value);
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            % RESET all the values based on the top scan, saves human time
            app.ColormapEditField.Value = 'hot(255)';
            ColormapEditFieldValueChanged(app, event);
            for ii = 1:length(app.scanParams)
                
                
                app.scanParams(ii).mask = 1;
                app.FirstsliceEditField.Value = num2str(app.UITable.Data{ii,7});
                app.LastsliceEditField.Value = num2str(app.UITable.Data{ii,8});
                app.FirstdynamicEditField.Value = num2str(app.UITable.Data{ii,5});
                app.LastdynamicEditField.Value = num2str(app.UITable.Data{ii,6});
                
                app.scanParams(ii).volumeSelect = app.UITable.Data{ii,6};
                app.scanParams(ii).volumeSelectFirst = app.UITable.Data{ii,5};
                app.scanParams(ii).sliceSelectFirst = app.UITable.Data{ii,7};
                app.scanParams(ii).sliceSelectLast = app.UITable.Data{ii,8};
                app.scanParams(ii).orientation = 3;
                
                app.scanParams(ii).ROI_box = [];
                app.scanParams(ii).polyROI = [];
                
            end
            %app.scanParams.mask = 1;
            
            app.tSNRColorbarmaxEditField.Value = 100;
            app.MeanColorbarmaxEditField.Value = '';
            app.OrientationEditField.Value = 3;
            
            app.EditField.Value = '';
            
        end

        % Button pushed function: DrawROIButton
        function DrawROIButtonPushed(app, event)
            
            dims=app.scanParams(1).dims ;
            for iScan = 2:length(app.scanParams)
                if ~isequal(dims,app.scanParams(iScan).dims)
                    warndlg('All scans must have the same dimensions to use an ROI');
                    return
                end
            end
            %volume = cbiReadNifti(app.scanParams(1).fileName);
            myvolume = MRIread(app.scanParams(1).fileName);
            volume = myvolume.vol;
            roiCoords = selectCropRegion(volume(:,end:-1:1,:,1));
            
            
            % Please note: this looks clunky because the image co-ords used later on
            % uses co-ordinates based on the rotated images. However, what would make
            % sense to the user is to include co-ordinates directly from the nifti
            % volume.
            
            niftiCoords = roiCoords;
            niftiCoords(:,2) = size(volume,2) - roiCoords([2:-1:1],2);
            
            % Quote the nifti-coordinates
            app.EditField.Value = mat2str(niftiCoords');
            %set(app.roiEditBox,'string',mat2str(niftiCoords'));
            
            for iScan = 1:length(app.scanParams)
                app.scanParams(iScan).ROI_box = mat2roiBox(roiCoords);
            end
            %guidata(hObject,data);
            function ROI_box = mat2roiBox(roiCoords)
                
                ROI_box.x = roiCoords(1,1);
                ROI_box.width = roiCoords(2,1)-roiCoords(1,1);
                ROI_box.y = roiCoords(1,2);
                ROI_box.height = roiCoords(2,2)-roiCoords(1,2);
                ROI_box.slice = roiCoords(1,3):roiCoords(2,3);
                
            end
            
        end

        % Button pushed function: DrawPolyButton
        function DrawPolyButtonPushed(app, event)
            %data = guidata(hObject);
            dims=app.scanParams(1).dims ;
            for iScan = 2:length(app.scanParams)
                if ~isequal(dims,app.scanParams(iScan).dims)
                    warndlg('All scans must have the same dimensions to use an ROI');
                    return
                end
            end
            myvolume = MRIread(app.scanParams(1).fileName);
            volume = myvolume.vol;
            %volume = cbiReadNifti(app.scanParams(1).fileName);
            
            [polymask, firstSlice, lastSlice, ign] = selectPoly_app(volume(:,end:-1:1,:,1));
            
            if ign==1 % this is a dodgy way to quickly get rid of any masks if the user pressed 'cancel' in 'selectPoly'
                clear polymask firstSlice lastSlice
                %     guidata(hObject, data)
            else
                
                polymask = (polymask~=0); % makes a mask image of 1s and 0s.
                
                app.scanParams.polyROI = polymask;
                app.scanParams.firstSlice = firstSlice;
                app.scanParams.lastSlice = lastSlice;
                %guidata(hObject,data);
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % gimmick :)
            if strcmpi(app.Button.Icon,'aesthetics/logo.png')
                app.Button.Icon = 'aesthetics/logo2.png';
            elseif strcmpi(app.Button.Icon,'aesthetics/logo2.png')
                app.Button.Icon = 'aesthetics/logo.png';
            end
        end

        % Button pushed function: FDButton
        function FDButtonPushed(app, event)
            myFD(app.scanParams)
        end

        % Value changed function: UsenoisescanforiSNRSwitch
        function UsenoisescanforiSNRSwitchValueChanged(app, event)
            value = app.UsenoisescanforiSNRSwitch.Value;
            
            
            if strcmpi(value,'On')
                %if value == 1
                
                app.Lamp_3.Color = [0 1 0];
                for ii = 1:length(app.scanParams)
                    app.scanParams(ii).dynNOISEscan = 1;
                end
                
            elseif strcmpi(value,'Off')
                %elseif value == 0
                
                app.Lamp_3.Color = [0.8 0.8 0.8];
                for ii = 1:length(app.scanParams)
                    app.scanParams(ii).dynNOISEscan = 0;
                end
                
            end
        end

        % Value changed function: Max5ofMaxsignalSwitch
        function Max5ofMaxsignalSwitchValueChanged(app, event)
            value = app.Max5ofMaxsignalSwitch.Value;
            % functionality for removing 5% of the max
            % sends a flag down to tSNR_report.m
            if strcmpi(value,'On')
                
                app.Lamp_2.Color = [0 1 0];
                
                for ii = 1:length(app.scanParams)
                    app.scanParams(ii).mask = 1;
                end
            elseif strcmpi(value,'Off')
                
                app.Lamp_2.Color = [0.8 0.8 0.8];
                
                for ii = 1:length(app.scanParams)
                    app.scanParams(ii).mask = 0;
                end
            end
        end

        % Button pushed function: RunreportButton
        function RunreportButtonPushed(app, event)
            %data = guidata(hObject);
            %keyboard
            
            for ii = 1:length(app.scanParams)
                app.scanParams(ii).recalculateTSNR = 1;
            end
            
            % add this check again, in case you delete the QA report folder
            % wituhout choosing files again (Dan)
            pathname = app.scanParams(1).mypath;
            if(~isfolder([pathname '/QA_report']))
               mkdir('QA_report');
            end
            %guidata(hObject,data);
            %scanParams = data.scanParams;
            %dat = get(data.scan_table,'dat');
            
            %scanParams = updateScanParams(scanParams,dat);
            disp('Running report....');
            %[tSNR_ROI,iSNR] = tSNR_report(scanParams,data.main_fig);
            %mean_image_report(scanParams,data.main_fig);
            
            %keyboard
            
            % redundancy
            UsenoisescanforiSNRSwitchValueChanged(app, event);
            Max5ofMaxsignalSwitchValueChanged(app, event);
            DiscardlastdynamicSwitchValueChange(app, event);
            
            tSNR_report_app(app,app.scanParams);
            mean_image_report_app(app,app.scanParams);
            generateHTMLReport_app(app.scanParams);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ChoosefilesButton = uibutton(app.UIFigure, 'push');
            app.ChoosefilesButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefilesButtonPushed, true);
            app.ChoosefilesButton.Text = 'Choose files';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Filename'; 'Scan #'; 'Notes'; 'Noise flag?'; 'First scan'; 'Last scan'; 'First slice'; 'Last slice'};
            app.UITable.RowName = {};
            app.RedoHTMLButton = uibutton(app.UIFigure, 'push');
            app.RedoHTMLButton.ButtonPushedFcn = createCallbackFcn(app, @RedoHTMLButtonPushed, true);
            app.RedoHTMLButton.Text = 'Redo HTML';
            app.DrawROIButton = uibutton(app.UIFigure, 'push');
            app.DrawROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawROIButtonPushed, true);
            app.DrawROIButton.Text = 'Draw ROI';
            app.DrawPolyButton = uibutton(app.UIFigure, 'push');
            app.DrawPolyButton.ButtonPushedFcn = createCallbackFcn(app, @DrawPolyButtonPushed, true);
            app.DrawPolyButton.Text = 'Draw Poly';
            app.LastsliceEditFieldLabel = uilabel(app.UIFigure);
            app.LastsliceEditFieldLabel.Text = 'Last slice';
            app.LastsliceEditField = uieditfield(app.UIFigure, 'text');
            app.LastsliceEditField.ValueChangedFcn = createCallbackFcn(app, @LastsliceEditFieldValueChanged, true);
            app.tSNRColorbarmaxEditFieldLabel = uilabel(app.UIFigure);
            app.tSNRColorbarmaxEditFieldLabel.Text = 'tSNR Colorbar max';
            app.tSNRColorbarmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.tSNRColorbarmaxEditField.Value = 100;
            app.ColormapEditFieldLabel = uilabel(app.UIFigure);
            app.ColormapEditFieldLabel.Text = 'Colormap';
            app.ColormapEditField = uieditfield(app.UIFigure, 'text');
            app.ColormapEditField.ValueChangedFcn = createCallbackFcn(app, @ColormapEditFieldValueChanged, true);
            app.ColormapEditField.Value = 'hot(255)';
            app.OrientationEditFieldLabel = uilabel(app.UIFigure);
            app.OrientationEditFieldLabel.Text = 'Orientation';
            app.OrientationEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrientationEditField.ValueChangedFcn = createCallbackFcn(app, @OrientationEditFieldValueChanged, true);
            app.OrientationEditField.Value = 3;
            app.RunreportButton = uibutton(app.UIFigure, 'push');
            app.RunreportButton.ButtonPushedFcn = createCallbackFcn(app, @RunreportButtonPushed, true);
            app.RunreportButton.Text = 'Run report';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.fMRIreportLabel = uilabel(app.UIFigure);
            app.fMRIreportLabel.Text = 'fMRI report ';
            app.FirstsliceEditFieldLabel = uilabel(app.UIFigure);
            app.FirstsliceEditFieldLabel.Text = 'First slice';
            app.FirstsliceEditField = uieditfield(app.UIFigure, 'text');
            app.FirstsliceEditField.ValueChangedFcn = createCallbackFcn(app, @FirstsliceEditFieldValueChanged2, true);
            app.FirstdynamicEditFieldLabel = uilabel(app.UIFigure);
            app.FirstdynamicEditFieldLabel.Text = 'First dynamic';
            app.FirstdynamicEditField = uieditfield(app.UIFigure, 'text');
            app.FirstdynamicEditField.ValueChangedFcn = createCallbackFcn(app, @FirstdynamicEditFieldValueChanged2, true);
            app.LastdynamicEditFieldLabel = uilabel(app.UIFigure);
            app.LastdynamicEditFieldLabel.Text = 'Last dynamic';
            app.LastdynamicEditField = uieditfield(app.UIFigure, 'text');
            app.LastdynamicEditField.ValueChangedFcn = createCallbackFcn(app, @LastdynamicEditFieldValueChanged2, true);
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.MeanColorbarmaxEditFieldLabel = uilabel(app.UIFigure);
            app.MeanColorbarmaxEditFieldLabel.Text = 'Mean Colorbar max';
            app.MeanColorbarmaxEditField = uieditfield(app.UIFigure, 'text');
            app.AppbyMichaelAsgharCodebyKevinAquinoLabel = uilabel(app.UIFigure);
            app.AppbyMichaelAsgharCodebyKevinAquinoLabel.Text = {'App by Michael Asghar'; 'Code by Kevin Aquino'};
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.FDButton = uibutton(app.UIFigure, 'push');
            app.FDButton.ButtonPushedFcn = createCallbackFcn(app, @FDButtonPushed, true);
            app.FDButton.Text = 'FD';
            app.DiscardlastdynamicSwitchLabel = uilabel(app.UIFigure);
            app.DiscardlastdynamicSwitchLabel.Text = 'Discard last dynamic?';
            app.DiscardlastdynamicSwitch = uiswitch(app.UIFigure, 'slider');
            app.DiscardlastdynamicSwitch.ValueChangedFcn = createCallbackFcn(app, @DiscardlastdynamicSwitchValueChange, true);
            app.UsenoisescanforiSNRSwitchLabel = uilabel(app.UIFigure);
            app.UsenoisescanforiSNRSwitchLabel.Text = 'Use noise scan for iSNR?';
            app.UsenoisescanforiSNRSwitch = uiswitch(app.UIFigure, 'slider');
            app.UsenoisescanforiSNRSwitch.ValueChangedFcn = createCallbackFcn(app, @UsenoisescanforiSNRSwitchValueChanged, true);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Max5ofMaxsignalSwitchLabel = uilabel(app.UIFigure);
            app.Max5ofMaxsignalSwitchLabel.Text = 'Max 5% of Max signal?';
            app.Max5ofMaxsignalSwitch = uiswitch(app.UIFigure, 'slider');
            app.Max5ofMaxsignalSwitch.ValueChangedFcn = createCallbackFcn(app, @Max5ofMaxsignalSwitchValueChanged, true);
            app.Max5ofMaxsignalSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fMRI_report_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end